classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Online_analysisUIFigure      matlab.ui.Figure
        TelecomDropDownLabel         matlab.ui.control.Label
        TelecomDropDown              matlab.ui.control.DropDown
        DataDropDownLabel            matlab.ui.control.Label
        DataDropDown                 matlab.ui.control.DropDown
        ChannelDropDownLabel         matlab.ui.control.Label
        ChannelDropDown              matlab.ui.control.DropDown
        GazetimeEditFieldLabel       matlab.ui.control.Label
        GazetimeEditField            matlab.ui.control.NumericEditField
        HarmsEditFieldLabel          matlab.ui.control.Label
        HarmsEditField               matlab.ui.control.NumericEditField
        SamplefreqEditFieldLabel     matlab.ui.control.Label
        SamplefreqEditField          matlab.ui.control.NumericEditField
        FiltersEditFieldLabel        matlab.ui.control.Label
        FiltersEditField             matlab.ui.control.NumericEditField
        MagnificationEditFieldLabel  matlab.ui.control.Label
        MagnificationEditField       matlab.ui.control.NumericEditField
        TimeAxes                     matlab.ui.control.UIAxes
        FreqAxes                     matlab.ui.control.UIAxes
        UITable2                     matlab.ui.control.Table
        STARTSwitch                  matlab.ui.control.Switch
        TragetfreqEditFieldLabel     matlab.ui.control.Label
        TragetfreqEditField          matlab.ui.control.EditField
        Lamp                         matlab.ui.control.Lamp
        ReloadButton                 matlab.ui.control.Button
        NameEditFieldLabel           matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        GroupEditFieldLabel          matlab.ui.control.Label
        GroupEditField               matlab.ui.control.EditField
        NoLabel                      matlab.ui.control.Label
        NoEditField                  matlab.ui.control.NumericEditField
        SaveButton                   matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        TotalTab                     matlab.ui.container.Tab
        CcaAxes                      matlab.ui.control.UIAxes
        IndiTab                      matlab.ui.container.Tab
        Cca2Axes                     matlab.ui.control.UIAxes
        ChoseTab_2                   matlab.ui.container.Tab
        Cca3Axes                     matlab.ui.control.UIAxes
        ChoseChannelEditFieldLabel   matlab.ui.control.Label
        ChoseChannelEditField        matlab.ui.control.EditField
        UITable3                     matlab.ui.control.Table
        ResultEditFieldLabel         matlab.ui.control.Label
        ResultEditField              matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        Status;
        ReceiveT=[];
        AnalyT=[];
        
        %实验参数
        Gazetime;
        HarmNum;
        FilterNum;
        Tragetfreq;
        Samplefreq;
        Magnification;
        
        
        hasData;        %表征串口是否接收到数据
        isShow;         %表征是否正在进行数据显示
        isStopDisp;     %表征是否按下了【暂停】按钮
        numRec;             %接收字符计数
        strRec;            %已接收的字符串
        %绘图句柄
        Timeplot;
        Freqplot;
        Ccaplot1;
        Ccaplot2;
        Ccaplot3;
        %通讯端口相关
        COMS;
        DataCom;
        TeleCom;
        scom;
        Num_Data_com_n;
        %实验数据
        RefData;
        AnalysisData;
        OrigtolData;
        TempData;
        OrigData;
        %实验结果
        TotalResult;
        IndivResult;
        
        %识别逻辑
        LastResult=0;
        JUDGE_COUNT;
        JUDGE_COUNT_END=2;
        
        %实时分析使用参数
        INIT_COUNT = 0;
        INIT_COUNT_END = 4;
    end

    methods (Access = public)
        function mode1(app)
             while ~get(app.scom, 'BytesAvailable')
               
                 app.receive(1);
                    app.AnalysisData = app.TempData;
                    app.OrigtolData = app.OrigData;
                    app.analytical_data(2);
                    app.judgeANDsend();

            end
           app.pause();
        end
    
        function mode2(app)
            app.receive(1);
            app.AnalysisData = app.TempData;
            app.OrigtolData = app.OrigData;
            app.analytical_data(2);
            app.Axesplot(1);
            app.judgeANDsend();
            app.pause();
            
        end
        
        function receive (app,divided)
            tic
            
            DATA_NUM = round(app.Gazetime * app.Samplefreq / divided);
            DATA_SIZE = 34;
            NUM = DATA_NUM * DATA_SIZE;
            receive_data = ReadSerialPort18(app.Num_Data_com_n, NUM);
            
            if(receive_data(1)==205 && receive_data(end)==205)
                 msgbox('蓝牙数据错误');
                 app.pause();
            end
            
            
            temp_data = zeros(DATA_NUM,DATA_SIZE);
            ori_data = zeros(DATA_NUM, 8);
            down_data = zeros(2, 8);
            dec_data = zeros(floor(DATA_NUM/2),8);
            
            receiveNum = size(receive_data,2);
            iRec = 1;
            iTem = 1;
            
            while iRec <= receiveNum-DATA_SIZE+1
                if(receive_data(iRec) == 85 && receive_data(iRec + 1) == 85 && receive_data(iRec + 2) == 170 && receive_data(iRec + 3) == 170)%% 55 55 AA AA 帧头
                    temp_data(iTem,:) = receive_data(:,iRec:iRec+DATA_SIZE-1);
                    
                    for coloum_j = 1:1:8  %%依次取出8个通道数据
                        coloum_i = 3 * coloum_j + 6; 
                        temp_dec = dec2dec(temp_data(iTem,coloum_i+1), temp_data(iTem, coloum_i+2), temp_data(iTem, coloum_i+3));                    
                        temp_value = ((temp_dec*4.5/(2^23-1))./app.Magnification)*10^6;
                        ori_data(iTem, coloum_j)=temp_value;
                        down_data(mod(iTem, 2)+1, coloum_j) = temp_value;
                    end
                    if mod(iTem, 2) == 0
                        dec_data(floor(iTem/2), :) = mean(down_data); %% 降采样后取平均值
                    end
                    
                    iTem = iTem + 1;
                    iRec = iRec + DATA_SIZE;
                else
                    iRec = iRec + 1;
                end
            end
            
            dec_data = dec_data(1:floor(DATA_NUM/2),:);
            ori_data = ori_data(1:DATA_NUM,:);
            dec_data = app.expend(dec_data,floor((iTem-1)/2));
            ori_data = app.expend(ori_data,iTem-1);
            
            app.OrigData = ori_data;
            app.TempData = dec_data;

                
%               temp = load('19-Mar-2019/YWH_road/simulate/No.1.mat');
%               app.OrigData = temp.timedata;
%               app.TempData = temp.timedata;
            temp=toc;
            app.ReceiveT = [app.ReceiveT , temp];
        end
        
        function analytical_data(app,mod)
            tic
            %% 准备数据
            
            
            if mod==2
            %% FFT
                N_fft=size(app.OrigtolData,1);
                if app.Gazetime>=1
                    
                    tongdao = app.ChannelDropDown.Value;
                    num_tongdao = str2double(tongdao(2:end));
                    
                %     N_fft = 1024;
                    fre_fft=app.Samplefreq;
                    t=0:1/fre_fft:(N_fft-1)/fre_fft;
                    f=(0:N_fft-1)*fre_fft/N_fft; 
                     
                    Ori_S_FFT = fft(app.OrigtolData(:,num_tongdao)',N_fft); 
                %     Ori_S_FFT_abs = (abs(Ori_S_FFT));
                    
                    app.Timeplot = plot(app.TimeAxes, t, app.OrigtolData(:,num_tongdao)');
                    
                    Ori_S_ln=20*log10(abs(Ori_S_FFT));
                    app.Freqplot = plot(app.FreqAxes, f(1,1:1:(floor(N_fft*80/fre_fft))), Ori_S_ln(1,1:1:(floor(N_fft*80/fre_fft))));
                    
                end
            end 
            
            dec_data = app.AnalysisData;
            
            %% 典型相关分析算法
            testdata = notch_filter(dec_data', app.Samplefreq / 2);
            app.TotalResult = test_fbcca_YWH(testdata, app.RefData, ...
                app.Tragetfreq, app.Samplefreq/2, app.FilterNum);
%             app.IndivResult = test_fbcca(testdata,  app.RefData,...
%                 app.Tragetfreq, app.Samplefreq/2, app.FilterNum,1:size(app.Tragetfreq,2));
            
            temp=toc;
            app.AnalyT = [app.AnalyT, temp];
        end
        
        function Axesplot(app,type)
            % type == 1 时是画总体的图，type == 2 时是画分别的图 , type == 3 时是画选择的图
            if type == 1
                app.Ccaplot1 = plot(app.CcaAxes, 1:length(app.TotalResult'), app.TotalResult');
                uitable_data(1,:)=app.Tragetfreq;
                uitable_data(2,:)=app.TotalResult;
                app.UITable2.Data = uitable_data;
            elseif type == 2
%                 app.Ccaplot2 = plot(app.Cca2Axes, 1:size(app.IndivResult,2), app.IndivResult);
%                 uitable_data(1,:)=app.Tragetfreq;
%                 uitable_data(2:size(app.Tragetfreq,2)+1,:)=app.IndivResult;
%                 app.UITable2.Data = uitable_data;
            elseif type == 3
%                 rho = test_fbcca_total(testdata, app.Tragetfreq, app.Samplefreq/2, app.HarmNum, app.FilterNum);
%                 app.Ccaplot3 = plot(app.Cca3Axes, 1:length(rho'), rho');
            end
        end
    
        function reference(app,divided)
            DATA_NUM = round(app.Gazetime * app.Samplefreq / divided);
            SampleNum = floor(DATA_NUM / 2);
%             app.RefData = cca_reference(app.Tragetfreq,...
%             app.Samplefreq/2,SampleNum*divided,app.HarmNum);
            app.RefData = cca_ref_4_13(app.Tragetfreq,app.Samplefreq/2, SampleNum*divided);
        end
    
        function judgeANDsend(app)
            p = app.TotalResult;
            [~,index] = max(p);
            index = index(1);
            
            MAX = p(index);
            TargetN = size(p,2);
            Average = (sum(p) - MAX) / (TargetN - 1);

            result = 1 - Average/MAX;
            
%             if result > 0.32
%                 if app.LastResult ~= index
%                     app.LastResult = index;
%                     app.JUDGE_COUNT = 1;
%                 elseif app.LastResult == index 
%                     app.JUDGE_COUNT = app.JUDGE_COUNT + 1;
%                 end
%                 
%                 if app.JUDGE_COUNT == app.JUDGE_COUNT_END
%                     
%                     fwrite(app.scom, [85 '0'+index 10]);
%                 
%                     app.ResultEditField.Value = app.Tragetfreq(index);
%                     app.JUDGE_COUNT = 1;
%                 end
%             else
%                 app.ResultEditField.Value = 0;
%             end
            
            
%             if app.LastResult ~= index
%                 app.LastResult = index;
%                 app.JUDGE_COUNT = 1;
%             elseif app.LastResult == index 
%                 app.JUDGE_COUNT = app.JUDGE_COUNT + 1;
%             end
%             
%             if app.JUDGE_COUNT == app.JUDGE_COUNT_END
%                 
%                 fwrite(app.scom, [85 '0'+index 10]);
%             
%                 app.ResultEditField.Value = app.Tragetfreq(index);
%                 app.JUDGE_COUNT = 1;
%             end
%             fwrite(app.scom, [85 '0'+index 10]);

%================Debug============
             if p(index)>0.34
              fwrite(app.scom, [85 48+index 10]);
             else
              fwrite(app.scom, [85 48 10]);
             end
              
%=================================
            
        end
    
        function pause(app)
            app.INIT_COUNT = 0;
            app.Status = "pause";
        end
    end
    
    methods (Static)
            
        function bytes(obj,~,app)

            %% 获取串口可获取的数据个数
            n = get(app.scom, 'BytesAvailable');
            %% 若串口有数据，接收所有数据
            if n
                %% 更新hasData参数，表明串口有数据需要显示
                app.hasData = true;
                %% 读取串口数据
                app.strRec = fread(obj, 1);
                %% 解析数据
                if app.strRec == 85 && ~app.isStopDisp
                   app.reference(app.INIT_COUNT_END);
                   app.mode1();
                elseif app.strRec == 86 && ~app.isStopDisp
                   app.reference(1);
                   app.mode2();
                end


                %% 更新参数
            %     setappdata(handles.figure1, 'numRec', numRec); %更新已接收的数据个数
            %     setappdata(handles.figure1, 'strRec', strRec); %更新要显示的字符串
            end
        end
    
        function output = expend(data,iEnd)
            DATA_NUM = size(data,1);
            
            if iEnd ~= DATA_NUM
                temp = data(iEnd-(DATA_NUM-iEnd-1):iEnd,:);
                data(iEnd+1:DATA_NUM,:) = flip(temp,1);
            end
            output = data;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            app.INIT_COUNT = 0;
            
            app.Gazetime = 2;
            app.HarmNum = 5;
            app.FilterNum = 5;
            app.Tragetfreq = [18.3 17 20.3 19 21 16.3 22.3 15.3 ];
            app.Samplefreq = 500;
            app.Magnification = 1;
            
            app.hasData = false;        %表征串口是否接收到数据
            app.isShow = false;         %表征是否正在进行数据显示
            app.isStopDisp = false;     %表征是否按下了【暂停】按钮
            app.numRec = 0;             %接收字符计数
            app.strRec = '';            %已接收的字符串
            
            app.COMS = "";
            app.DataCom = "Undefine";
            app.TeleCom = "Undefine";
            app.scom = "";
            app.Num_Data_com_n = 0;
            
            app.GazetimeEditField.Value = app.Gazetime;
            app.HarmsEditField.Value = app.HarmNum;
            app.FiltersEditField.Value = app.FilterNum;
            app.TragetfreqEditField.Value = "[18.3 17 20.3 19 21 16.3 22.3 15.3 ]";
            app.SamplefreqEditField.Value = app.Samplefreq;
            app.MagnificationEditField.Value = app.Magnification;
            app.NoEditField.Value = 1;
            
            app.COMS = seriallist;
            if isempty(app.COMS)
                app.COMS = "No available serial";
            end
            app.TelecomDropDown.Items = ["",app.COMS,"Click to update"];
            app.DataDropDown.Items = ["",app.COMS,"Click to update"];
            
            app.UITable2.Data = [];
            app.STARTSwitch.Value = 'Off';
            app.TelecomDropDown.Value = "";
            app.DataDropDown.Value = "";
            
            addpath('./function');
            
            app.Lamp.Color = 'g';
        end

        % Value changed function: TragetfreqEditField
        function InputTragetFreq(app, event)
            value = app.TragetfreqEditField.Value;
            value = string(value);
            app.Tragetfreq = eval(value);
        end

        % Value changed function: GazetimeEditField
        function InputGaze(app, event)
            value = app.GazetimeEditField.Value;
            app.Gazetime = value;
        end

        % Value changed function: HarmsEditField
        function InputHarm(app, event)
            value = app.HarmsEditField.Value;
            app.HarmNum = value;
        end

        % Value changed function: SamplefreqEditField
        function InputSample(app, event)
            value = app.SamplefreqEditField.Value;
            app.Samplefreq = value;
        end

        % Value changed function: MagnificationEditField
        function InputMagnification(app, event)
            value = app.MagnificationEditField.Value;
            app.Magnification = value;
        end

        % Value changed function: STARTSwitch
        function COMstart(app, event)
            value = app.STARTSwitch.Value;
            if strcmp(value, 'On')
                delete(instrfind);
                baud_rate = 9600;
                jiaoyan = 'none';
                data_bits = 8;
                stop_bits = 1; 
                tempCom = convertStringsToChars(app.DataCom);
                app.Num_Data_com_n = str2double(tempCom(4:end));
                app.scom = serial(app.TeleCom);

                set(app.scom, 'BaudRate', baud_rate, 'Parity', jiaoyan, 'DataBits',...
                data_bits, 'StopBits', stop_bits, 'BytesAvailableFcnCount', 1,...
                'BytesAvailableFcnMode', 'byte', 'BytesAvailableFcn', {@app.bytes,app},...
                'TimerPeriod', 0.05);
                    try
                        fopen(app.scom);  %打开串口
                        StartSerialPort5(app.Num_Data_com_n);%%Num_Data_com_n 代表数据来源端口号，范围COM1-COM9，不可大于com9
                    catch 
                        msgbox('串口不可获得！');
                        app.STARTSwitch.Value = 'Off';
                        return;
                    end
                
                
                app.Lamp.Color = 'r';
            else
                t = timerfind;
                if ~isempty(t)
                    stop(t);
                    delete(t);
                end
                
                stopasync(app.scom);
                fclose(app.scom);
                delete(app.scom);
                app.Lamp.Color = 'g';
            end
        end

        % Value changed function: TelecomDropDown
        function UpdataTele(app, event)
            value = app.TelecomDropDown.Value;
            if value == "Click to update"
                tempCOMS = app.COMS(app.COMS ~= app.DataCom);
                if isempty(tempCOMS) && app.DataCom == "Undefine"
                    tempCOMS = "No available serial";
                end
                app.TelecomDropDown.Items = ["",tempCOMS,"Click to update"];
                app.TelecomDropDown.Value = "";
            elseif value ~= "" && value ~= "No available serial"
                app.TeleCom = value;
                tempCOMS = app.COMS(app.COMS ~= app.TeleCom);
                if isempty(tempCOMS) && app.DataCom == "Undefine"
                    tempCOMS = "No available serial";
                end
                app.DataDropDown.Items = ["",tempCOMS,"Click to update"];
            end
        end

        % Value changed function: DataDropDown
        function UpdataData(app, event)
            value = app.DataDropDown.Value;
            if value == "Click to update"
                app.COMS = seriallist;
                tempCOMS = app.COMS(app.COMS ~= app.TeleCom);
                if isempty(tempCOMS)
                    tempCOMS = "No available serial";
                end
                app.DataDropDown.Items = ["",tempCOMS,"Click to update"];
                app.DataDropDown.Value = "";
            elseif value ~= "" && value ~= "No available serial"
                app.DataCom = value;
                tempCOMS = app.COMS(app.COMS ~= app.DataCom);
                if isempty(tempCOMS) && app.TeleCom == "Undefine"
                    tempCOMS = "No available serial";
                end
                app.TelecomDropDown.Items = ["",tempCOMS,"Click to update"];
            end
        end

        % Button pushed function: ReloadButton
        function Reload(app, event)
            t = timerfind;
            if ~isempty(t)
                stop(t);
                delete(t);
            end
            
            stopasync(app.scom);
            fclose(app.scom);
            delete(app.scom);
            app.Lamp.Color = 'g';
            delete(app.Timeplot);
            delete(app.Freqplot);
            delete(app.Ccaplot1);
            delete(app.Ccaplot2);
            app.init();
        end

        % Button pushed function: SaveButton
        function SaveData(app, event)
            name = app.NameEditField.Value;
            group = app.GroupEditField.Value;
            data = app.UITable2.Data;
            timedata = app.OrigData;
            timedata2 = app.AnalysisData;
            Date = date;
            
            if ~exist(Date,'dir')
                mkdir(Date);
            end
            
            if ~exist(name,'dir')
                mkdir([Date '\' name]);
            end
            
            if ~exist([Date '\' name '\' group],'dir')
                mkdir([Date '\' name '\' group]);
            end
            
            save([Date '\' name '\' group '\' 'No.' num2str(app.NoEditField.Value) '.mat'],'data','timedata','timedata2');
            app.NoEditField.Value = app.NoEditField.Value + 1;
        end

        % Value changed function: NoEditField
        function CheckNo(app, event)
            value = app.NoEditField.Value;
            if value < 1
                app.NoEditField.Value = 1;
            end
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.NoEditField.Value = 1;
        end

        % Value changed function: GroupEditField
        function GroupEditFieldValueChanged(app, event)
            app.NoEditField.Value = 1;
        end

        % Value changed function: ChoseChannelEditField
        function ChoseChannelEditFieldValueChanged(app, event)
            value = app.ChoseChannelEditField.Value;
            value = string(value);
            ChoseChannel = eval(value);
            data = app.Data(ChoseChannel , :);
            Axesplot(app, data,3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Online_analysisUIFigure = uifigure('Visible', 'off');
            app.Online_analysisUIFigure.Name = 'Online_analysis';
            app.TelecomDropDownLabel = uilabel(app.Online_analysisUIFigure);
            app.TelecomDropDownLabel.Text = 'Telecom';
            app.TelecomDropDown = uidropdown(app.Online_analysisUIFigure);
            app.TelecomDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13', 'COM14', 'COM15'};
            app.TelecomDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdataTele, true);
            app.TelecomDropDown.Value = 'COM1';
            app.DataDropDownLabel = uilabel(app.Online_analysisUIFigure);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.Online_analysisUIFigure);
            app.DataDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13', 'COM14', 'COM15'};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdataData, true);
            app.DataDropDown.Value = 'COM1';
            app.ChannelDropDownLabel = uilabel(app.Online_analysisUIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.Online_analysisUIFigure);
            app.ChannelDropDown.Items = {'T1', 'T2', 'T3', 'T4', 'T5', 'T6', 'T7', 'T8'};
            app.ChannelDropDown.Value = 'T1';
            app.GazetimeEditFieldLabel = uilabel(app.Online_analysisUIFigure);
            app.GazetimeEditFieldLabel.Text = 'Gaze time';
            app.GazetimeEditField = uieditfield(app.Online_analysisUIFigure, 'numeric');
            app.GazetimeEditField.ValueChangedFcn = createCallbackFcn(app, @InputGaze, true);
            app.HarmsEditFieldLabel = uilabel(app.Online_analysisUIFigure);
            app.HarmsEditFieldLabel.Text = 'Harms';
            app.HarmsEditField = uieditfield(app.Online_analysisUIFigure, 'numeric');
            app.HarmsEditField.ValueChangedFcn = createCallbackFcn(app, @InputHarm, true);
            app.SamplefreqEditFieldLabel = uilabel(app.Online_analysisUIFigure);
            app.SamplefreqEditFieldLabel.Text = 'Sample freq';
            app.SamplefreqEditField = uieditfield(app.Online_analysisUIFigure, 'numeric');
            app.SamplefreqEditField.ValueChangedFcn = createCallbackFcn(app, @InputSample, true);
            app.FiltersEditFieldLabel = uilabel(app.Online_analysisUIFigure);
            app.FiltersEditFieldLabel.Text = 'Filters';
            app.FiltersEditField = uieditfield(app.Online_analysisUIFigure, 'numeric');
            app.MagnificationEditFieldLabel = uilabel(app.Online_analysisUIFigure);
            app.MagnificationEditFieldLabel.Text = 'Magnification';
            app.MagnificationEditField = uieditfield(app.Online_analysisUIFigure, 'numeric');
            app.MagnificationEditField.ValueChangedFcn = createCallbackFcn(app, @InputMagnification, true);
            app.TimeAxes = uiaxes(app.Online_analysisUIFigure);
            title(app.TimeAxes, 'Time field')
            app.FreqAxes = uiaxes(app.Online_analysisUIFigure);
            title(app.FreqAxes, 'Freq field')
            app.UITable2 = uitable(app.Online_analysisUIFigure);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.STARTSwitch = uiswitch(app.Online_analysisUIFigure, 'slider');
            app.STARTSwitch.ValueChangedFcn = createCallbackFcn(app, @COMstart, true);
            app.TragetfreqEditFieldLabel = uilabel(app.Online_analysisUIFigure);
            app.TragetfreqEditFieldLabel.Text = 'Traget freq';
            app.TragetfreqEditField = uieditfield(app.Online_analysisUIFigure, 'text');
            app.TragetfreqEditField.ValueChangedFcn = createCallbackFcn(app, @InputTragetFreq, true);
            app.Lamp = uilamp(app.Online_analysisUIFigure);
            app.ReloadButton = uibutton(app.Online_analysisUIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @Reload, true);
            app.ReloadButton.Text = 'Reload';
            app.NameEditFieldLabel = uilabel(app.Online_analysisUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Online_analysisUIFigure, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.GroupEditFieldLabel = uilabel(app.Online_analysisUIFigure);
            app.GroupEditFieldLabel.Text = 'Group';
            app.GroupEditField = uieditfield(app.Online_analysisUIFigure, 'text');
            app.GroupEditField.ValueChangedFcn = createCallbackFcn(app, @GroupEditFieldValueChanged, true);
            app.NoLabel = uilabel(app.Online_analysisUIFigure);
            app.NoLabel.Text = 'No.';
            app.NoEditField = uieditfield(app.Online_analysisUIFigure, 'numeric');
            app.NoEditField.ValueChangedFcn = createCallbackFcn(app, @CheckNo, true);
            app.SaveButton = uibutton(app.Online_analysisUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData, true);
            app.SaveButton.Text = 'Save';
            app.TabGroup = uitabgroup(app.Online_analysisUIFigure);
            app.TotalTab = uitab(app.TabGroup);
            app.TotalTab.Title = 'Total';
            app.CcaAxes = uiaxes(app.TotalTab);
            title(app.CcaAxes, 'CCA')
            app.IndiTab = uitab(app.TabGroup);
            app.IndiTab.Title = 'Indi';
            app.Cca2Axes = uiaxes(app.IndiTab);
            title(app.Cca2Axes, 'CCA2')
            app.ChoseTab_2 = uitab(app.TabGroup);
            app.ChoseTab_2.Title = 'Chose';
            app.Cca3Axes = uiaxes(app.ChoseTab_2);
            title(app.Cca3Axes, 'CCA3')
            app.ChoseChannelEditFieldLabel = uilabel(app.ChoseTab_2);
            app.ChoseChannelEditFieldLabel.Text = 'Chose Channel';
            app.ChoseChannelEditField = uieditfield(app.ChoseTab_2, 'text');
            app.ChoseChannelEditField.ValueChangedFcn = createCallbackFcn(app, @ChoseChannelEditFieldValueChanged, true);
            app.UITable3 = uitable(app.Online_analysisUIFigure);
            app.UITable3.ColumnName = '';
            app.UITable3.RowName = {};
            app.ResultEditFieldLabel = uilabel(app.Online_analysisUIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.Online_analysisUIFigure, 'numeric');
            app.ResultEditField.Editable = 'off';
            % Show the figure after all components are created
            app.Online_analysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Online_analysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Online_analysisUIFigure)
        end
    end
end